classdef App_automatische_Analyse_eines_Regelkreises_Kopie < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Menu                          matlab.ui.container.Menu
        TabGroup_2                    matlab.ui.container.TabGroup
        ZeroPoleTab                   matlab.ui.container.Tab
        PZDropDown                    matlab.ui.control.DropDown
        PZTable                       matlab.ui.control.UIAxes
        pzplotUIAxes                  matlab.ui.control.UIAxes
        NyquistTab                    matlab.ui.container.Tab
        NyquistInfo                   matlab.ui.control.UIAxes
        nyquist_UIAxes2               matlab.ui.control.UIAxes
        BodeTab                       matlab.ui.container.Tab
        Bode_UIAxes                   matlab.ui.control.UIAxes
        BandwidthTab                  matlab.ui.container.Tab
        Bandwidth_UIAxes              matlab.ui.control.UIAxes
        TabGroup                      matlab.ui.container.TabGroup
        ResponseTab                   matlab.ui.container.Tab
        ButtonGroup                   matlab.ui.container.ButtonGroup
        r_d12Button                   matlab.ui.control.RadioButton
        SetpointButton                matlab.ui.control.RadioButton
        StepButton                    matlab.ui.control.RadioButton
        keyparamTable                 matlab.ui.control.UIAxes
        Response_UIAxes               matlab.ui.control.UIAxes
        StationaryValuesTab           matlab.ui.container.Tab
        JumpLabel                     matlab.ui.control.Label
        ButtonGroup_2                 matlab.ui.container.ButtonGroup
        SetpointandDisturbanceButton  matlab.ui.control.RadioButton
        OutputdisturbanceButton       matlab.ui.control.RadioButton
        SetButton                     matlab.ui.control.RadioButton
        keyparamTable_2               matlab.ui.control.UIAxes
        Controllanderrorplo           matlab.ui.control.UIAxes
        Panel                         matlab.ui.container.Panel
        LoadButton                    matlab.ui.control.Button
        SaveButton                    matlab.ui.control.Button
        controlloopstabilityLabel     matlab.ui.control.Label
        AnalyzeButton                 matlab.ui.control.Button
        Updated2Button                matlab.ui.control.Button
        Updated1Button                matlab.ui.control.Button
        UpdaterButton                 matlab.ui.control.Button
        UpdateKButton                 matlab.ui.control.Button
        UpdateGButton                 matlab.ui.control.Button
        Image                         matlab.ui.control.Image
        testaxis                      matlab.ui.control.UIAxes
        d_2_UIAxes                    matlab.ui.control.UIAxes
        d_1_UIAxes                    matlab.ui.control.UIAxes
        r_UIAxes                      matlab.ui.control.UIAxes
        K_UIAxes                      matlab.ui.control.UIAxes
        G_UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        G
        K
        r
        r_s
        d_1
        d_1_s
        d_2
        d_2_s
        T
        t
        r_num_sections
        d1_num_sections
        d2_num_sections
        timeDescription
        cansysjump
        S
        L
        GS
        KS
        char_eq
        N_G
        Z_G
        N_K
        Z_K
        G_poles
        G_zeroes
        K_poles
        K_zeroes
        L_poles
        L_zeroes
        T_poles
        T_zeroes
        S_poles
        S_zeroes
        GS_poles
        GS_zeroes
        KS_poles
        KS_zeroes
    end
    
    methods (Access = private)


function displayTransferFunctionInApp(app, G, label)

    % Extract numerator and denominator coefficients
    [num, den] = tfdata(G, 'v');
    
    % Convert num and den to latex
    num_str = polyToString(num);
    den_str = polyToString(den);
    
    % Combine numerator and denominator into LaTeX format
    formula = ['$$' label '(s) = \frac{' num_str '}{' den_str '}$$'];
    
    % Find the appropriate UIAxes based on the label
    uiAxesName = [label '_UIAxes'];
    uiAxesHandle = app.(uiAxesName);
    
    % Display the formula in the app using LaTeX interpreter
    cla(uiAxesHandle);
    text(uiAxesHandle, 0.5, 0.5, formula, 'Interpreter', 'latex', 'FontSize', 14, 'HorizontalAlignment', 'center');
    
end

        
function displayPlaceholder(app, label, variable)

    % Placeholder formula to be displayed
    placeholderFormula = ['$$' label '(' variable ') = ???$$'];
    
    uiAxesName = [label '_UIAxes'];
    uiAxesHandle = app.(uiAxesName);

    % Display the placeholder formula in the app using LaTeX interpreter
    text(uiAxesHandle, 0.5, 0.5, placeholderFormula, 'Interpreter', 'latex', 'FontSize', 14, 'HorizontalAlignment', 'center');
end
        
        
function displayTimeFunction(app, target_value, num_sections, label)

    
    syms t s;

    % initialize variables to save the formulas 
    % in time domain and laplace domain
    timeFormula = 0;
    transferFormula = 0;
    latexTimeStr = '';
    latexTransferStr = '';


    if num_sections == 1 %check if function is definded piecewise or not
        % first case: function is continous

    
    % Check if the target_value is a function handle or a constant
    if isa(target_value, 'function_handle')
        % Handle time-dependent function
        timeFormula = target_value(t);
        latexTimeStr = latex(timeFormula);
        
        % Convert time function to transfer function (Laplace Transform)
        transferFormula = laplace(timeFormula, t, s);

        appHandle= transferFormula;
        latexTransferStr = latex(transferFormula);
    else
        transferFormula = laplace(target_value(t), t, s);

        % Handle constant value
        latexTimeStr = num2str(target_value);
        
        % Convert constant to transfer function
        transferFormula = target_value;
        latexTransferStr = num2str(transferFormula);
    end


    else % function was defined piecewise

         % loop for the piecewise definded function handles
    if isa(target_value, 'function_handle')
        target_value = {target_value}; % Convert single function handle to cell array for uniform processing
    end


    for i = 1:length(target_value)
        if isa(target_value{i}, 'function_handle')
            % Handle time-dependent function
            timePart = target_value{i}(t);
            latexTimeStr = [latexTimeStr, ' ; ', latex(timePart)];
            
            % Convert time function to transfer function (Laplace Transform)
            transferPart = laplace(timePart, t, s);
            latexTransferStr = [latexTransferStr, ' ; ', latex(transferPart)];
            
            % Add to overall formulas
            timeFormula = timeFormula + timePart;
            transferFormula = transferFormula + transferPart;
        else
            error('All elements in target_value must be function handles.');
        end
    end
    
    % Remove leading ';' from Latex-String
    if startsWith(latexTimeStr, ' ; ')
        latexTimeStr = latexTimeStr(4:end);
    end
        % Remove leading ';' from Latex-String
    if startsWith(latexTransferStr, ' ; ')
        latexTransferStr = latexTransferStr(4:end);
    end

    end

    % Convert Laplace result into transfer function
    [num, den] = numden(transferFormula);
    num = sym2poly(num);
    den = sym2poly(den);
    transferFunction = tf(num, den);
    
    % Save tranfer function in app
    appName = [label '_s'];
    app.(appName) = transferFunction;
    
    % combine label and Latex formula
    combinedFormula = ['$$' label '(t) = ' latexTimeStr ' \quad \Rightarrow \quad ' label '(s) = ' latexTransferStr '$$'];

    % find corresponding UIAxes based on label
    uiAxesName = [label '_UIAxes'];
    uiAxesHandle = app.(uiAxesName);
    cla(uiAxesHandle);

    % show the combined formula in the app using Latex interpreter
    text(uiAxesHandle, 0.5, 0.5, combinedFormula, 'Interpreter', 'latex', 'FontSize', 14, 'HorizontalAlignment', 'center');
end


function char_eq = get_characteristic_equation(app, G, K)
    % Extract numerator and denominator polynomials
    [num_G, den_G] = tfdata(G, 'v');
    [num_K, den_K] = tfdata(K, 'v');

    app.N_G = num_G;
    app.Z_G = den_G;
    app.N_K = num_K;
    app.Z_K = den_K;

    % Create symbolic variables
    syms s;
    N_g = poly2sym(den_G, s);
    Z_g = poly2sym(num_G, s);
    N_k = poly2sym(den_K, s);
    Z_k = poly2sym(num_K, s);

    % Calculate the characteristic equation
    char_eq = expand(N_g * N_k + Z_g * Z_k);


    % Convert to LaTeX string
    latex_str = ['$', latex(char_eq), ' = 0$'];


    % Display in the specified axes
    %text(app.CharForm_UIAxes, 0.5, 0.5, latex_str, 'Interpreter', 'latex', 'FontSize', 14, 'HorizontalAlignment','center');
end


function latex_string = generate_latex_string(app, system, system_name)
    % Calculate poles and zeroes
    poles = pole(system);
    zeroes = zero(system);
    
    % Generate LaTeX string
    latex_string = ['$' system_name '(s) \Rightarrow \, '];
    
    % Append zeroes to the string
    for i = 1:length(zeroes)
        latex_string = [latex_string, ' x_{z', num2str(i), '} = ', num2str(zeroes(i)), ', \, \ \ '];
    end
    
    % Append poles to the string
    for i = 1:length(poles)
        latex_string = [latex_string, ' x_{p', num2str(i), '} = ', num2str(poles(i)), ', \, \ \ '];
    end
    
    % Remove last comma and space, and close the LaTeX string
    latex_string = [latex_string(1:end-8), '$'];
    
    % Display LaTeX string in UIAxes
    uiAxesName = ['poles_and_zeroes_' system_name '_UIAxes'];
    uiAxesHandle = app.(uiAxesName); 
    cla(uiAxesHandle); % Clear existing content
    text(uiAxesHandle, 0.5, 0.5, latex_string, 'Interpreter', 'latex', 'FontSize', 14, 'HorizontalAlignment', 'center');
end


function checkAndDisplayUnstableCancellation(app)

    
    % Check for unstable pole-zero cancellation
    isUnstableCancellation1 = checkUnstablePoleZeroCancellation(app.Z_G, app.N_K);
    isUnstableCancellation2 = checkUnstablePoleZeroCancellation(app.Z_K, app.N_G);
    
    % Determine the text message
    if isUnstableCancellation1 && isUnstableCancellation2
        message = 'Both systems have unstable pole-zero cancellations!';
    elseif isUnstableCancellation1 || isUnstableCancellation2
        message = 'One of the systems has an unstable pole-zero cancellation!';
    else
        message = 'No unstable pole-zero cancellations detected.';
    end
    
    % Display the message in the label
    app.pazcancel_label.Text = message;
end


function displayTransferFunctionInfo(app, tf_sys, label)
    % Set up the axis for displaying the results
    uiAxesName = [label '_InfoUIAxes'];
    uiAxesHandle = app.(uiAxesName);
    cla(uiAxesHandle); % Clear existing plots
    
    % Determine stability and type
    stability = routh_hurwitz_tf(tf_sys);
    if sternlyproper_tf(tf_sys)
        type = 'Sternly proper';
    elseif proper_tf(tf_sys)
        type = 'Proper';
    else
        type = 'Improper';
    end
    
    % Create LaTeX string
    latex_str = createLatexString(tf_sys, label, stability, type);
    
    % Display the LaTeX string in the axis
    text(uiAxesHandle, 0.5, 0.5, latex_str, 'Interpreter', 'latex', 'FontSize', 14, 'HorizontalAlignment', 'center');
end
        
function controlloopstable(app)
     if control_loop_stability(app.G,app.K,app.S) == true  
        message = 'The control loop is stable';
    else
        message = 'The control loop is unstable';
     end
    % Display the message in the label
    app.controlloopstabilityLabel.Text = message;
            
        end
        

function plot_TS_bandwidth(app, T, S)
    % Calculate bandwidth of the transfer function T
    bandwidth_T = bandwidth(T);

    % Get frequency response for T
    [magT, phaseT, omega] = bode(T);
    magT = squeeze(magT);
    omega = squeeze(omega);
    
    % Get frequency response for S
    [magS, ~] = bode(S, omega);
    magS = squeeze(magS);

    % Convert magnitude to dB
    magT_dB = 20 * log10(magT);
    magS_dB = 20 * log10(magS);

    % Ensure the UIAxes is cleared before plotting
    cla(app.Bandwidth_UIAxes);

    % Plot the magnitude response of T
    semilogx(app.Bandwidth_UIAxes, omega, magT_dB, 'b', 'LineWidth', 2);
    hold(app.Bandwidth_UIAxes, 'on');
    
    % Plot the magnitude response of S
    semilogx(app.Bandwidth_UIAxes, omega, magS_dB, 'r', 'LineWidth', 2);
    
    grid(app.Bandwidth_UIAxes, 'on');
    
    % Mark the -3 dB point
    semilogx(app.Bandwidth_UIAxes, [min(omega), max(omega)], [-3, -3], 'k--', 'LineWidth', 1);

    % Mark the bandwidth of T at the correct frequency
    ylims = ylim(app.Bandwidth_UIAxes);
    semilogx(app.Bandwidth_UIAxes, [bandwidth_T, bandwidth_T], ylims, 'g--', 'LineWidth', 2);
    
    hold(app.Bandwidth_UIAxes, 'off');
    legend(app.Bandwidth_UIAxes, 'T(s)', 'S(s)', '-3 dB Point', 'Bandwidth T', 'Location', 'Best');

end

        
function plot_bode_margins(app, L)
    % Check if the input is a valid transfer function
    if ~isa(L, 'tf')
        error('Input must be a transfer function object');
    end
    
    % Calculate margins
    [Gm, Pm, Wcg, Wcp] = margin(L);
    
    % Convert gain margin to dB
    Gm_dB = 20 * log10(Gm);
    
    % Set up the axis for displaying the Bode plot
    uiAxesHandle = app.Bode_UIAxes;
    cla(uiAxesHandle); % Clear existing plots
    
    % Plot the Bode diagram using bodeplot with options to show margins
    h = bodeplot(uiAxesHandle, L);
    setoptions(h, 'FreqUnits', 'Hz', 'Grid', 'on');
    
    % Add annotations within the axis
    annotationStr = {
        ['Gain Margin: ', num2str(Gm_dB, '%.2f'), ' dB'], ...
        ['Phase Margin: ', num2str(Pm, '%.2f'), '°'], ...
        ['Gain Crossover Frequency: ', num2str(Wcg, '%.2f'), ' rad/s'], ...
        ['Phase Crossover Frequency: ', num2str(Wcp, '%.2f'), ' rad/s']
    };
    
    % Display the annotation within the axis
    text(uiAxesHandle, 'Units', 'normalized', 'Position', [0.1, 0.1], ...
        'String', annotationStr, 'FontSize', 10, 'BackgroundColor', 'white', ...
        'EdgeColor', 'black', 'Margin', 5);
end

        
function render_latex_table(app)

G_table = createLatexStringfortransfer(app.G, 'G'); 
K_table = createLatexStringfortransfer(app.K, 'K'); 
L_table = createLatexStringfortransfer(app.L, 'L'); 
T_table = createLatexStringfortransfer(app.T, 'T'); 
S_table = createLatexStringfortransfer(app.S, 'S'); 
GS_table = createLatexStringfortransfer(app.GS, 'GS'); 
KS_table = createLatexStringfortransfer(app.KS, 'KS'); 

[G_stable, G_proper, G_sternly] = check_tf_conditions(app.G);
[K_stable, K_proper, K_sternly] = check_tf_conditions(app.K);
[L_stable, L_proper, L_sternly] = check_tf_conditions(app.L);
[T_stable, T_proper, T_sternly] = check_tf_conditions(app.T);
[S_stable, S_proper, S_sternly] = check_tf_conditions(app.S);
[GS_stable, GS_proper, GS_sternly] = check_tf_conditions(app.GS);
[KS_stable, KS_proper, KS_sternly] = check_tf_conditions(app.KS);


    % Create a LaTeX table
tableStr = ['$$\begin{array}{|c|c|c|c|} \hline \mbox{Transferfunction} &\mbox{Stable} & \mbox{Proper} & \mbox{Strictly Proper} \\ \hline \mbox{' G_table '} &' G_stable '&' G_proper '&' G_sternly '\\ \hline \mbox{' K_table '} &' K_stable '&' K_proper '&' K_sternly '\\ \hline \mbox{' L_table '} &' L_stable '&' L_proper '&' L_sternly '\\ \hline \mbox{' T_table '} &' T_stable '&' T_proper '&' T_sternly '\\ \hline \mbox{' S_table '} &' S_stable '&' S_proper '&' S_sternly '\\ \hline \mbox{' GS_table '} &' GS_stable '&' GS_proper '&' GS_sternly '\\ \hline \mbox{' KS_table '} &' KS_stable '&' KS_proper '&' KS_sternly '\\ \hline \end{array}$$'];


    % Use the existing axis for rendering LaTeX
    uiAxesHandle = app.testaxis;
    cla(uiAxesHandle); % Clear previous contents
    
    % Adjust the axis properties to better fit the table
    axis(uiAxesHandle, 'off');

text(uiAxesHandle, 0.5, 0.5, tableStr, 'Interpreter', 'latex', 'FontSize', 14, 'HorizontalAlignment', 'center');

end

        
function plotnyquist(app)
   

    % Plot Nyquist plot using nyquistSiso function
    [lReal, lImag, ~] = nyquistSiso(app.L);
    
    
    plot(app.nyquist_UIAxes2, lReal, lImag,'b','LineWidth',2);
    grid (app.nyquist_UIAxes2, 'on');
    hold (app.nyquist_UIAxes2, 'on');
    plot(app.nyquist_UIAxes2, lReal(1),lImag(1),'oc','LineWidth',2);
    plot(app.nyquist_UIAxes2, lReal(end),lImag(end),'xc','LineWidth',2);
    plot(app.nyquist_UIAxes2, -1,0,'+r','LineWidth',2);
    xlabel(app.nyquist_UIAxes2, 'Real Axis');
    ylabel(app.nyquist_UIAxes2, 'Imaginary Axis');
    title(app.nyquist_UIAxes2, 'Nyquist Diagram for \omega=0...+\infty, o=L(j0), x=L(j\infty), +=critical point');
 
    % Count poles and calculate delta phi
    [m_0, n_0, deltaphi] = countPolesforNyquist(app.L);

    % Check for pole-zero cancellation
    if checkUnstablePoleZeroCancellation(app.G, app.K)
        pzcancel = 'with';
    else
        pzcancel = 'without';
    end
    
    % Check if L(s) is proper
    if ~proper_tf(app.L)
        lprop = 'not';
    else
        lprop = '';
    end
    
    % Convert numbers to strings
    m_0_str = num2str(m_0);
    n_0_str = num2str(n_0);
    deltaphi_str = num2str(deltaphi);

    % Create LaTeX table string
    tableStr = ['$$ \begin{array}{|c|} \hline \mbox{Information for Nyquist-Analysis} \\ \hline \mbox{G(s) and K(s) ' pzcancel ' pole/zero cancellation} \\ \hline \mbox{L(s) is ' lprop ' proper} \\ \hline m_0 = ' m_0_str ' \\ \hline n_0 = ' n_0_str ' \\ \hline \Delta \varphi = ' deltaphi_str ' \\ \hline \end{array} $$'];
    
    % Use the existing axis for rendering LaTeX
    uiAxesHandle = app.NyquistInfo;
    cla(uiAxesHandle); % Clear previous contents
    
    % Adjust the axis properties to better fit the table
    axis(uiAxesHandle, 'off');
    text(uiAxesHandle, 0.5, 0.5, tableStr, 'Interpreter', 'latex', 'FontSize', 14, 'HorizontalAlignment', 'center');                    
end


function calculateAndDisplayResponse(app, selectedButton)
    app.Response_UIAxes.Visible = 'on'; 
    
    % Check if the time vector is set, if not, open the set time window
    if isempty(app.t)
        app.t = create_linear_time_vector(app.r_num_sections);
        return; % Exit the function to wait for the time input
    end
    
    % Generate the input vector based on the function r
    input_function_values = generate_Function_Values_Version2(app.r);

    dis_1 = generate_Function_Values_Version2(app.d_1);
    dis_2 = generate_Function_Values_Version2(app.d_2);
    

    % different if-cases depending on which button is selected by the user

    if selectedButton == app.StepButton
        % Calculate and display Step response
        % avoid using  '[y, t_out] = step(app.T)' because it changes dimensions of y
        % using lsim instead and keeping the needed vector length 

        % Define the system (app.T)
        sys = app.T; 

        % Generate the step input signal for the existing time vector
        u = ones(length(app.t), 1); % Step input

        % Simulate the system response using lsim
        [y, t_out] = lsim(sys, u, app.t);

        responseTitle = 'System Step Response';
        cla(app.Response_UIAxes);
        plot(app.Response_UIAxes, t_out, y, 'DisplayName', 'System Response');

        displayKeyParameters(app, y)

    elseif selectedButton == app.SetpointButton
        % Calculate and display response to the custom function r
        disp('input:')
        disp(length(input_function_values))
        disp('app.t:')
        disp(length(app.t))
        y = lsim(app.T, input_function_values, app.t);
        responseTitle = 'System Response to Setpoint r';
        cla(app.Response_UIAxes);
        plot(app.Response_UIAxes, app.t, y, 'DisplayName', 'System Response');
        
        % Plot the custom function r
        hold(app.Response_UIAxes, 'on');
        plot(app.Response_UIAxes, app.t, input_function_values, '--', 'DisplayName', 'Input Function r');
        hold(app.Response_UIAxes, 'off');

        displayKeyParameters(app, y)

    elseif selectedButton == app.r_d12Button
        % Define the unity feedback system
        closed_loop_system = feedback(app.L, 1);

        % Calculate the system response to the target value
        y_target = lsim(closed_loop_system, input_function_values, app.t);

        % Calculate the system response to disturbances
        H_d1 = feedback(1, app.L); % Transfer function for disturbance D1
        H_d2 = app.L * feedback(1, app.L); % Transfer function for disturbance D2

        [y_d1, ~] = lsim(H_d1, dis_1, app.t);
        [y_d2, ~] = lsim(H_d2, dis_2, app.t);


        % Total system response
        y_total = y_target + y_d1 + y_d2;

        error = input_function_values - y_total;
        
        responseTitle = 'System Response to Setpoint r and Disturbances d_1 and d_2';
        cla(app.Response_UIAxes);
        plot(app.Response_UIAxes, app.t, y_total, 'DisplayName', 'System Response');

        
        % Plot the custom function r
        hold(app.Response_UIAxes, 'on');
        plot(app.Response_UIAxes, app.t, y_d1, '--', 'DisplayName', 'Disturbance d_1');
        hold(app.Response_UIAxes, 'off');

        % Plot the custom function d_1
        hold(app.Response_UIAxes, 'on');
        plot(app.Response_UIAxes, app.t, y_d2, '--', 'DisplayName', 'Disturbance d_2');
        hold(app.Response_UIAxes, 'off');

        % Plot the custom function d_1
        hold(app.Response_UIAxes, 'on');
        plot(app.Response_UIAxes, app.t, error, '--', 'DisplayName', 'Error');
        hold(app.Response_UIAxes, 'off');

        displayKeyParameters(app, y_total)


    end
    
    % Set title and labels
    title(app.Response_UIAxes, responseTitle);
    xlabel(app.Response_UIAxes, 'Time (s)');
    ylabel(app.Response_UIAxes, 'Amplitude');
    
    % Enable grid
    grid(app.Response_UIAxes, 'on');
    
    % Show legend
    legend(app.Response_UIAxes, 'show');
end


function setTimeAndClose(app)

    app.t = create_linear_time_vector(app.r_num_sections); 

    % Generate LaTeX-compatible string
    latexStr = ['$\mbox{From ', num2str(startTime), ' to ', num2str(endTime), ' with ', num2str(stepSize), ' steps}$'];
    app.timeDescription = latexStr;
  
end
        
function displayKeyParameters(app, y)

   
% Erzeuge ein Beispiel-Struct mit stepinfo
info = stepinfo(y, app.t);

% Extrahiere einzelne Datenfelder
RiseTime = info.RiseTime;
SettlingTime = info.SettlingTime;
SettlingMin = info.SettlingMin;
SettlingMax = info.SettlingMax;
Overshoot = info.Overshoot;
Undershoot = info.Undershoot;
Peak = info.Peak;
PeakTime = info.PeakTime;
TransientTime = info.TransientTime;

% Konvertiere Zahlen in Strings
RiseTime_str = num2str(RiseTime);
SettlingTime_str = num2str(SettlingTime);
SettlingMin_str = num2str(SettlingMin);
SettlingMax_str = num2str(SettlingMax);
Overshoot_str = num2str(Overshoot);
Undershoot_str = num2str(Undershoot);
Peak_str = num2str(Peak);
PeakTime_str = num2str(PeakTime);
TransientTime_str = num2str(TransientTime);

% Generiere die Tabelle als LaTeX-String
tableStr = ['$$\begin{array}{|c|c|} \hline \mbox{Keyparameter of a Response} & \mbox{Value} \\ \hline ', ...
    '\mbox{Rise time } t_r & ' RiseTime_str ' \\ \hline ', ...
    '\mbox{Settling time ($2\% band$) } t_s & ' SettlingTime_str ' \\ \hline ', ...
    '\mbox{SettlingMin} & ' SettlingMin_str ' \\ \hline ', ...
    '\mbox{SettlingMax} & ' SettlingMax_str ' \\ \hline ', ...
    '\mbox{Overshoot} & ' Overshoot_str ' \\ \hline ', ...
    '\mbox{Undershoot} & ' Undershoot_str ' \\ \hline ', ...
    '\mbox{Peak} & ' Peak_str ' \\ \hline ', ...
    '\mbox{PeakTime} & ' PeakTime_str ' \\ \hline ', ...
    '\mbox{TransientTime} & ' TransientTime_str ' \\ \hline \end{array}$$'];


    % Use the existing axis for rendering LaTeX
    uiAxesHandle = app.keyparamTable;
    cla(uiAxesHandle); % Clear previous contentsuild
    
    % Adjust the axis properties to better fit the table
    axis(uiAxesHandle, 'off');
    text(uiAxesHandle, 0.5, 0.5, tableStr, 'Interpreter', 'latex', 'FontSize', 18, 'HorizontalAlignment', 'center');
            
        end
        
   function calculateAndDisplayErrorandOutput(app, selectedButton)
    app.Controllanderrorplo.Visible = 'on';

    [y_stat_TR, e_stat_SR, y_stat_SD1, e_stat_SD1, e_stat, app.cansysjump] = calculateStationaryValues(app.T, app.S, app.r_s, app.d_1_s, app.d_2_s, app.G);

    % Check if the time vector is set, if not, open the set time window
    if isempty(app.t)
        app.t = create_linear_time_vector(app.r_num_sections);
        return; % Exit the function to wait for the time input
    end

    % Generate the input vector based on the functions
    input_r = generate_Function_Values_Version2(app.r);
    input_d1 = generate_Function_Values_Version2(app.d_1);

    if selectedButton == app.SetButton
        % Setpoint = const and d_1 = d_2 = eta = 0
        y_TR = lsim(app.T, input_r, app.t);
        e_SR = lsim(app.S, input_r, app.t);
        responseTitle = 'Function if Setpoint = const and d_1 = d_2 = eta = 0';
        cla(app.Controllanderrorplo);
        plot(app.Controllanderrorplo, app.t, y_TR, 'DisplayName', 'Output');
        hold(app.Controllanderrorplo, 'on');
        plot(app.Controllanderrorplo, app.t, e_SR, '--', 'DisplayName', 'Error');
        hold(app.Controllanderrorplo, 'off');

        displayStationaryValues(app, y_stat_TR, e_stat_SR);

    elseif selectedButton == app.OutputdisturbanceButton
        % Output disturbance = const and r = d_1 = eta = 0
        y_SD1 = lsim(app.S, input_d1, app.t);
        e_SD1 = lsim(-1*app.S, input_d1, app.t);
        responseTitle = 'Function if Output disturbance = const and r = d_1 = eta = 0';
        cla(app.Controllanderrorplo);
        plot(app.Controllanderrorplo, app.t, y_SD1, 'DisplayName', 'Output');
        hold(app.Controllanderrorplo, 'on');
        plot(app.Controllanderrorplo, app.t, e_SD1, '--', 'DisplayName', 'Error');
        hold(app.Controllanderrorplo, 'off');

        displayStationaryValues(app, y_stat_SD1, e_stat_SD1);

    elseif selectedButton == app.SetpointandDisturbanceButton
        % Setpoint = const | Output disturbance = const and d_1 = eta = 0
        e = lsim(app.S, input_r, app.t) - lsim(app.G * app.S, input_d1, app.t);
        responseTitle = 'Function if Setpoint = const | Output disturbance = const and d_1 = eta = 0';
        cla(app.Controllanderrorplo);
        plot(app.Controllanderrorplo, app.t, e, 'DisplayName', 'Error');

        displayStationaryValues(app, 0.0001, e_stat);

    end


    % Set title and labels
    title(app.Controllanderrorplo, responseTitle);
    xlabel(app.Controllanderrorplo, 'Time (s)');
    ylabel(app.Controllanderrorplo, 'Amplitude');

    % Enable grid
    grid(app.Controllanderrorplo, 'on');

    % Show legend
    legend(app.Controllanderrorplo, 'show');
end


function displayStationaryValues(app, y, e)


    if y == 0.0001
        y_str = '';
    else
    
    y_str = ['$ y_\infty ='  char(vpa(y, 4)) '$'];
    end

    e_str = ['$ e_\infty ='  char(vpa(e, 4)) '$'];

    if app.cansysjump ~= 0
            message = 'The control loop is Jumpable';
    else
        message = 'The control loop is not Jumpable';
     end
    % Display the message in the label
    app.JumpLabel.Text = message;


    % Create LaTeX table string
    tableStr = ['$$ \begin{array}{|c|} \hline \mbox{Stationary Values} \\\hline \mbox{' y_str '}  \\  \hline \mbox{' e_str '} \\ \hline \end{array}$$'];

    % Use the existing axis for rendering LaTeX
    uiAxesHandle = app.keyparamTable_2;
    cla(uiAxesHandle); % Clear previous contents
    
    % Adjust the axis properties to better fit the table
    axis(uiAxesHandle, 'off');
    text(uiAxesHandle, 0.5, 0.5, tableStr, 'Interpreter', 'latex', 'FontSize', 18, 'HorizontalAlignment', 'center');
            
        end
        function PZchange(app)
                  % Get the selected value from the dropdown menu
    app.pzplotUIAxes.Visible = 'on';
    selectedValue = app.PZDropDown.Value;
    
    % Remove (s) from the selected string
    varName = strrep(selectedValue, '(s)', '');
    
    % Create the corresponding variable dynamically
    switch varName
        case 'G'
            sys = app.G; % Assume app.G is already defined
        case 'K'
            sys = app.K; % Assume app.K is already defined
        case 'L'
            sys = app.L; % Assume app.L is already defined
        case 'T'
            sys = app.T; % Assume app.T is already defined
        case 'S'
            sys = app.S; % Assume app.S is already defined
        case 'GS'
            sys = app.GS; % Assume app.GS is already defined
        case 'KS'
            sys = app.KS; % Assume app.KS is already defined
        otherwise
            error('Unknown transfer function selected.');
    end
    
    % Calculate poles and zeroes
    poles = pole(sys);
    zeroes = zero(sys);

    % Convert poles and zeroes to LaTeX strings
    polesStr = '';
    zeroesStr = '';
    for i = 1:length(poles)
        polesStr = [polesStr, ' x_{p', num2str(i), '} = ', num2str(poles(i)), ', \, '];
    end
    for j = 1:length(zeroes)
        zeroesStr = [zeroesStr, ' x_{z', num2str(j), '} = ', num2str(zeroes(j)), ', \, '];
    end
    polesStr = polesStr(1:end-4); % Remove last ', \, '
    zeroesStr = zeroesStr(1:end-4); % Remove last ', \, '
    
    % Create a LaTeX table
tableStr = ['$$\begin{array}{|c|} \hline \mbox{Poles and Zeroes of the Transferfunction} \\ \hline \\ \hline ' polesStr ' \\ \hline ' zeroesStr ' \\ \hline \end{array} $$'];


    % Use the existing axis for rendering LaTeX
    uiAxesHandle = app.PZTable;
    cla(uiAxesHandle); % Clear previous contents
    
    % Adjust the axis properties to better fit the table
    axis(uiAxesHandle, 'off');

    text(uiAxesHandle, 0.5, 0.5, tableStr, 'Interpreter', 'latex', 'FontSize', 16, 'HorizontalAlignment', 'center');

    
    % Save poles and zeroes as dynamic variables
    app.([varName '_poles']) = poles;
    app.([varName '_zeroes']) = zeroes;


    % Display pole-zero plot on the specified axis
    
    pzplot(app.pzplotUIAxes, sys);
    end


    function build_G(app)

    % Get the button text as the function label
    label = 'G';

    % Get the transfer function from the user
    app.G = get_user_transfer_function();
    
    % If user didn't cancel, display the transfer function
    if ~isempty(app.G)
        displayTransferFunctionInApp(app, app.G, label);
    end

                
    end


    function build_K(app)

    % Get the button text as the function label
    label = 'K';

    app.K = get_user_controller();

    if ~isempty(app.K)
        displayTransferFunctionInApp(app, app.K, label);
            
    end
    end


   function build_r(app)
   % Get the button text as the function label
   label = 'r';

   [app.r, app.r_num_sections] = get_target_value_version2();
            
   % in case r was definded already and is changed
   % the time vector changes as well
   % hence t needs to be defined new:
   app.t = create_linear_time_vector(app.r_num_sections);
    if ~isempty(app.r)
   displayTimeFunction(app, app.r, app.r_num_sections, label)
    end
   end


function saveAppVariables(app)
    % List of available variables in the app
    variables = {
        'G', 'K', 'r', 'r_s', 'd_1', 'd_1_s', 'd_2', 'd_2_s', 'T', 't', 'r_num_sections', ...
        'd1_num_sections', 'd2_num_sections', 'timeDescription', 'cansysjump', 'S', 'L', 'GS', 'KS', ...
        'char_eq', 'N_G', 'Z_G', 'N_K', 'Z_K', 'G_poles', 'G_zeroes', 'K_poles', 'K_zeroes', ...
        'L_poles', 'L_zeroes', 'T_poles', 'T_zeroes', 'S_poles', 'S_zeroes', 'GS_poles', 'GS_zeroes', ...
        'KS_poles', 'KS_zeroes'
    };

    % Initialize structure to save variables
    varsStruct = struct();

    % Assign values from app to the structure
    for i = 1:length(variables)
        varsStruct.(variables{i}) = app.(variables{i});
    end

    % Prompt user for filename
    filename = inputdlg('Enter filename (without extension):', 'Save Variables');
    if isempty(filename)
        msgbox('No filename entered. Operation cancelled.', 'Error', 'error');
        return;
    end
    filename = filename{1};
    
    % Remove invalid characters from filename
    invalidChars = {'<', '>', ':', '"', '/', '\', '|', '?', '*'};
    for i = 1:length(invalidChars)
        filename = strrep(filename, invalidChars{i}, '');
    end
    
    % Save the structure to a .mat file
    save([filename, '.mat'], '-struct', 'varsStruct');

    % Display confirmation message 
    msgbox('All done! Your variables are now saved!', 'Save Successful', 'help');
end

        
function loadAppVariables(app)
    % Prompt user for filename
    [filename, pathname] = uigetfile('*.mat', 'Select the .mat file');
    if isequal(filename, 0) || isequal(pathname, 0)
        msgbox('No file selected. Operation cancelled.', 'Error', 'error');
        return;
    end
    fullFilename = fullfile(pathname, filename);

    % Check if the file exists
    if ~isfile(fullFilename)
        msgbox('File does not exist. Please check the filename and try again.', 'Error', 'error');
        return;
    end

    % Load the variables from the .mat file
    varsStruct = load(fullFilename);

    % List of variables to be loaded into the app
    variables = {
        'G', 'K', 'r', 'r_s', 'd_1', 'd_1_s', 'd_2', 'd_2_s', 't', 'r_num_sections', 'd1_num_sections', 'd2_num_sections'
    };

    % Assign values from the structure to the app
    for i = 1:length(variables)
        app.(variables{i}) = varsStruct.(variables{i});
    end

    % Display friendly confirmation message
    msgbox('All done! Your variables are now loaded and ready to go!', 'Load Successful', 'help');
end


    end %end of private methods

    methods (Access = public)
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            displayPlaceholder(app, 'G', 's')
            displayPlaceholder(app, 'K', 's')
            displayPlaceholder(app, 'r', 't')
            displayPlaceholder(app, 'd_1', 't')
            displayPlaceholder(app, 'd_2', 't')
            
           
        end

        % Button pushed function: UpdateGButton
        function UpdateGButtonPushed(app, event)

        build_G(app);

        end

        % Button pushed function: UpdateKButton
        function UpdateKButtonPushed(app, event)
            build_K(app);
        end

        % Button pushed function: UpdaterButton
        function UpdaterButtonPushed(app, event)
            build_r(app);
        end

        % Button pushed function: Updated1Button
        function Updated1ButtonPushed(app, event)
            label = 'd_1';
            
            [app.d_1, app.d1_num_sections] = get_disturbance_input_version2();

            displayTimeFunction(app, app.d_1, app.d1_num_sections, label)
        end

        % Button pushed function: Updated2Button
        function Updated2ButtonPushed(app, event)
            label = 'd_2';

            [app.d_2, app.d2_num_sections] = get_disturbance_input_version2();

            displayTimeFunction(app, app.d_2, app.d2_num_sections, label)
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            

            % when analyse button is pushsed
            % first check if all neccessary inputs are given
            % G, K, r(t) and t
            % if d1 and d2 are empty, they will be set to 0

            %check if G was not entered
                if isempty(app.G)
                build_G(app);
                end

            %check if K was not entered
                if isempty(app.K)
                build_K(app);
                end

            %check if r(t) was not entered
                if isempty(app.r)
                build_r(app);
                end

           app.t = create_linear_time_vector(app.r_num_sections);


            %check if d1(t) and d2(t) were not entered 
            % if this is the case, set both to zero
                if isempty(app.d_1)
                app.d_1 = @(t) 0 + 0*t;
                app.d1_num_sections = 1;
                label = 'd_1';
                displayTimeFunction(app, app.d_1, app.d1_num_sections, label)
                end    

                if isempty(app.d_2)
                app.d_2 = @(t) 0 + 0*t;
                app.d2_num_sections = 1;
                label = 'd_2';
                displayTimeFunction(app, app.d_1, app.d1_num_sections, label)
                end


            [app.T, app.S, app.L, app.GS, app.KS] = transferfunctions(app.G, app.K);

            render_latex_table(app);
                
            app.PZDropDown.Visible = 'on';

            app.SaveButton.Visible = 'on';

            app.ButtonGroup.Visible = 'on';

            app.ButtonGroup_2.Visible = 'on';

            plotnyquist(app);

            app.char_eq = get_characteristic_equation(app, app.G, app.K);

            controlloopstable(app);

            plot_TS_bandwidth(app, app.T, app.S);

            plot_bode_margins(app, app.L);

            calculateAndDisplayErrorandOutput(app, app.ButtonGroup_2.SelectedObject);

            calculateAndDisplayResponse(app, app.ButtonGroup.SelectedObject);

            PZchange(app);


        end

        % Value changed function: PZDropDown
        function PZDropDownValueChanged(app, event)
            PZchange(app);


        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)

            selectedButton = app.ButtonGroup.SelectedObject;

            calculateAndDisplayResponse(app, selectedButton);
        

        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_2.SelectedObject;
    
            calculateAndDisplayErrorandOutput(app, selectedButton);

            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            saveAppVariables(app);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            loadAppVariables(app);
            displayTransferFunctionInApp(app, app.G, 'G');
            displayTransferFunctionInApp(app, app.K, 'K');
            displayTimeFunction(app, app.r, app.r_num_sections, 'r');
            displayTimeFunction(app, app.d_1, app.d1_num_sections, 'd_1');
            displayTimeFunction(app, app.d_2, app.d2_num_sections, 'd_2');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.Panel = uipanel(app.UIFigure);
            app.G_UIAxes = uiaxes(app.Panel);
            app.G_UIAxes.XTick = [];
            app.G_UIAxes.YTick = [];
            app.G_UIAxes.ZTick = [];
            app.G_UIAxes.Visible = 'off';
            app.K_UIAxes = uiaxes(app.Panel);
            app.K_UIAxes.XTick = [];
            app.K_UIAxes.YTick = [];
            app.K_UIAxes.ZTick = [];
            app.K_UIAxes.Visible = 'off';
            app.r_UIAxes = uiaxes(app.Panel);
            app.r_UIAxes.XTick = [];
            app.r_UIAxes.YTick = [];
            app.r_UIAxes.ZTick = [];
            app.r_UIAxes.Visible = 'off';
            app.d_1_UIAxes = uiaxes(app.Panel);
            app.d_1_UIAxes.XTick = [];
            app.d_1_UIAxes.YTick = [];
            app.d_1_UIAxes.ZTick = [];
            app.d_1_UIAxes.Visible = 'off';
            app.d_2_UIAxes = uiaxes(app.Panel);
            app.d_2_UIAxes.XTick = [];
            app.d_2_UIAxes.YTick = [];
            app.d_2_UIAxes.ZTick = [];
            app.d_2_UIAxes.Visible = 'off';
            app.testaxis = uiaxes(app.Panel);
            xlabel(app.testaxis, 'X')
            ylabel(app.testaxis, 'Y')
            zlabel(app.testaxis, 'Z')
            app.testaxis.Visible = 'off';
            app.Image = uiimage(app.Panel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'App Bilder', 'Standardregelkreis.png');
            app.UpdateGButton = uibutton(app.Panel, 'push');
            app.UpdateGButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateGButtonPushed, true);
            app.UpdateGButton.Text = 'Update G';
            app.UpdateKButton = uibutton(app.Panel, 'push');
            app.UpdateKButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateKButtonPushed, true);
            app.UpdateKButton.Text = 'Update K';
            app.UpdaterButton = uibutton(app.Panel, 'push');
            app.UpdaterButton.ButtonPushedFcn = createCallbackFcn(app, @UpdaterButtonPushed, true);
            app.UpdaterButton.Text = 'Update r';
            app.Updated1Button = uibutton(app.Panel, 'push');
            app.Updated1Button.ButtonPushedFcn = createCallbackFcn(app, @Updated1ButtonPushed, true);
            app.Updated1Button.Text = 'Update d1';
            app.Updated2Button = uibutton(app.Panel, 'push');
            app.Updated2Button.ButtonPushedFcn = createCallbackFcn(app, @Updated2ButtonPushed, true);
            app.Updated2Button.Text = 'Update d2';
            app.AnalyzeButton = uibutton(app.Panel, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.controlloopstabilityLabel = uilabel(app.Panel);
            app.controlloopstabilityLabel.Text = '';
            app.SaveButton = uibutton(app.Panel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Visible = 'off';
            app.SaveButton.Text = 'Save';
            app.LoadButton = uibutton(app.Panel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ResponseTab = uitab(app.TabGroup);
            app.ResponseTab.Title = 'Response';
            app.Response_UIAxes = uiaxes(app.ResponseTab);
            title(app.Response_UIAxes, 'Title')
            xlabel(app.Response_UIAxes, 'X')
            ylabel(app.Response_UIAxes, 'Y')
            zlabel(app.Response_UIAxes, 'Z')
            app.Response_UIAxes.Visible = 'off';
            app.keyparamTable = uiaxes(app.ResponseTab);
            xlabel(app.keyparamTable, 'X')
            ylabel(app.keyparamTable, 'Y')
            zlabel(app.keyparamTable, 'Z')
            app.keyparamTable.Visible = 'off';
            app.ButtonGroup = uibuttongroup(app.ResponseTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Visible = 'off';
            app.StepButton = uiradiobutton(app.ButtonGroup);
            app.StepButton.Text = 'Step';
            app.StepButton.Value = true;
            app.SetpointButton = uiradiobutton(app.ButtonGroup);
            app.SetpointButton.Text = 'Setpoint';
            app.r_d12Button = uiradiobutton(app.ButtonGroup);
            app.r_d12Button.Text = 'Setpoint and Disturbances';
            app.StationaryValuesTab = uitab(app.TabGroup);
            app.StationaryValuesTab.Title = 'Stationary Values';
            app.Controllanderrorplo = uiaxes(app.StationaryValuesTab);
            title(app.Controllanderrorplo, 'Title')
            xlabel(app.Controllanderrorplo, 'X')
            ylabel(app.Controllanderrorplo, 'Y')
            zlabel(app.Controllanderrorplo, 'Z')
            app.Controllanderrorplo.Visible = 'off';
            app.keyparamTable_2 = uiaxes(app.StationaryValuesTab);
            xlabel(app.keyparamTable_2, 'X')
            ylabel(app.keyparamTable_2, 'Y')
            zlabel(app.keyparamTable_2, 'Z')
            app.keyparamTable_2.Visible = 'off';
            app.ButtonGroup_2 = uibuttongroup(app.StationaryValuesTab);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.ButtonGroup_2.Visible = 'off';
            app.SetButton = uiradiobutton(app.ButtonGroup_2);
            app.SetButton.Text = 'Setpoint';
            app.SetButton.Value = true;
            app.OutputdisturbanceButton = uiradiobutton(app.ButtonGroup_2);
            app.OutputdisturbanceButton.Text = 'Outputdisturbance';
            app.SetpointandDisturbanceButton = uiradiobutton(app.ButtonGroup_2);
            app.SetpointandDisturbanceButton.Text = 'Setpoint and Outputdisturbance';
            app.JumpLabel = uilabel(app.StationaryValuesTab);
            app.JumpLabel.Text = '';
            app.TabGroup_2 = uitabgroup(app.UIFigure);
            app.ZeroPoleTab = uitab(app.TabGroup_2);
            app.ZeroPoleTab.Title = 'Zero/Pole';
            app.pzplotUIAxes = uiaxes(app.ZeroPoleTab);
            title(app.pzplotUIAxes, 'Title')
            xlabel(app.pzplotUIAxes, 'X')
            ylabel(app.pzplotUIAxes, 'Y')
            zlabel(app.pzplotUIAxes, 'Z')
            app.pzplotUIAxes.Visible = 'off';
            app.PZTable = uiaxes(app.ZeroPoleTab);
            xlabel(app.PZTable, 'X')
            ylabel(app.PZTable, 'Y')
            zlabel(app.PZTable, 'Z')
            app.PZTable.Visible = 'off';
            app.PZDropDown = uidropdown(app.ZeroPoleTab);
            app.PZDropDown.Items = {'G(s)', 'K(s)', 'L(s)', 'T(s)', 'S(s)', 'GS(s)', 'KS(s)'};
            app.PZDropDown.ValueChangedFcn = createCallbackFcn(app, @PZDropDownValueChanged, true);
            app.PZDropDown.Visible = 'off';
            app.PZDropDown.Value = 'G(s)';
            app.NyquistTab = uitab(app.TabGroup_2);
            app.NyquistTab.Title = 'Nyquist';
            app.nyquist_UIAxes2 = uiaxes(app.NyquistTab);
            title(app.nyquist_UIAxes2, 'Nyquist Diagram for \omega=0...+\infty, o=L(j0), x=L(j\infty), +=critical point')
            xlabel(app.nyquist_UIAxes2, 'Real Axis')
            ylabel(app.nyquist_UIAxes2, 'Imaginary Axis')
            zlabel(app.nyquist_UIAxes2, 'Z')
            app.nyquist_UIAxes2.Visible = 'off';
            app.NyquistInfo = uiaxes(app.NyquistTab);
            xlabel(app.NyquistInfo, 'X')
            ylabel(app.NyquistInfo, 'Y')
            zlabel(app.NyquistInfo, 'Z')
            app.NyquistInfo.Visible = 'off';
            app.BodeTab = uitab(app.TabGroup_2);
            app.BodeTab.Title = 'Bode';
            app.Bode_UIAxes = uiaxes(app.BodeTab);
            title(app.Bode_UIAxes, 'Title')
            xlabel(app.Bode_UIAxes, 'X')
            ylabel(app.Bode_UIAxes, 'Y')
            zlabel(app.Bode_UIAxes, 'Z')
            app.Bode_UIAxes.Visible = 'off';
            app.BandwidthTab = uitab(app.TabGroup_2);
            app.BandwidthTab.Title = 'Bandwidth';
            app.Bandwidth_UIAxes = uiaxes(app.BandwidthTab);
            title(app.Bandwidth_UIAxes, 'Magnitude Response with Bandwidth')
            xlabel(app.Bandwidth_UIAxes, 'Frequency (rad/s)')
            ylabel(app.Bandwidth_UIAxes, 'Magnitude (dB)')
            zlabel(app.Bandwidth_UIAxes, 'Z')
            app.Bandwidth_UIAxes.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_automatische_Analyse_eines_Regelkreises_Kopie

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end